classdef Future_Climate_Change_Pathways < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FutureClimateChangePathwaysLabel  matlab.ui.control.Label
        AboutPanel_2                 matlab.ui.container.Panel
        Hyperlink_9                  matlab.ui.control.Hyperlink
        ForlastversionclickLabel_2   matlab.ui.control.Label
        AuthorsLabel_2               matlab.ui.control.Label
        Hyperlink_8                  matlab.ui.control.Hyperlink
        Hyperlink_7                  matlab.ui.control.Hyperlink
        DatasourcesLabel             matlab.ui.control.Label
        TemperatureLabel             matlab.ui.control.Label
        AtmosphericCO2Label          matlab.ui.control.Label
        EmissionsLabel               matlab.ui.control.Label
        Hyperlink_3                  matlab.ui.control.Hyperlink
        Hyperlink_2                  matlab.ui.control.Hyperlink
        Hyperlink                    matlab.ui.control.Hyperlink
        AnnualEmissionsGtofCO2Panel  matlab.ui.container.Panel
        In2030Slider                 matlab.ui.control.Slider
        In2030SliderLabel            matlab.ui.control.Label
        UncertaintyLabel             matlab.ui.control.Label
        Switch                       matlab.ui.control.Switch
        In2150Slider                 matlab.ui.control.Slider
        In2150Label                  matlab.ui.control.Label
        In2115Slider                 matlab.ui.control.Slider
        In2115Label                  matlab.ui.control.Label
        In2080Slider                 matlab.ui.control.Slider
        In2080Label                  matlab.ui.control.Label
        In2050Slider                 matlab.ui.control.Slider
        Emissionsin2047Label         matlab.ui.control.Label
        UITemperture                 matlab.ui.control.UIAxes
        UIEmissions                  matlab.ui.control.UIAxes
        UICarbon                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %Initial values
        E2015 = 35; % Emissions at 2015
        E2030 = 45; % Emissions at 2030
        E2050 = 60; % Emissions at 2050
        E2080 = 60; % Emissions at 2080
        E2115 = 40; % Emissions at 2115
        E2150 = 35; % Emissions at 2150

        Uncertainty_flag=0;
       
     
    end
    
    methods (Access = private)

    %%%%%%%%%%%%
function [ M_ppm, T, T_quantiles ] = trajectory_climate(app, emissions, timestep, quantiles_want )

% Returns trajectory of atmospheric carbon (ppm) and temperature (wrt 1900) from year 2015 on, 
% given vector of CO2 emissions (Gt C per year) at intervals of "timestep"  years starting in year 2015.
% If final argument is provided, then needs to be a 2-element vector giving
% desired percentiles for distribution of temperature (between 0 and 1)

% Last edited: September 8, 2022 by Derek Lemoine


%% Parameters Might Adjust

Params.climatemodel = 'update'; % 'dice' or 'update'; determines whether use DICE-2016R climate dynamics or Geoffroy et al dynamics (see Dietz et al. 2020); default: 'update'
Params.carbonmodel = 'joos'; % 'dice','gol','joos'; determines whether use DICE-2016R carbon dynamics or one of the other models (see Dietz et al. 2020); default: 'joos'


%% Fixed Parameters

switch nargin
    case 3
        quantiles_want = 0; % no uncertainty
    case 4
        if max(quantiles_want)>1 || min(quantiles_want)<0
            error('Quantiles need to be between 0 and 1, inclusive');
        end
end

Params.timestep = timestep; % timestep, in years; default: 5

switch Params.carbonmodel
    case 'dice' % DICE-2016R
        Params.Mmatrix = [1-0.12 0.12*588/360 0; 0.12 1-0.12*588/360-0.007 0.007*360/1720; 0 0.007 1-0.007*360/1720]; % transition matrix for carbon
        Params.Mmatrix = Params.Mmatrix^(1/5); % annualize
        Params.emsinks = [1 0 0]; % 1x3 allocation of emissions to CO2 reservoirs; default: [1 0 0]
        Params.M0 = [ 851 460 1740 ]; % 1x3; year 2015 CO2 stocks (Gt C); default: [ 851 460 1740 ]
    case 'gol' % Golosov et al. (2014), via Lemoine (2020) and with starting stocks from Dietz et al. (2020)
        Params.Mmatrix = bsxfun(@times,eye(2,2),[1 1-0.0023]); % 2x2 CO2 transition matrix; default: bsxfun(@times,eye(2,2),[1 1-0.0023])
        Params.emsinks = [0.2 0.393*(1-0.2)]; % 1x2 allocation of emissions to CO2 reservoirs; default: [0.2 0.393*(1-0.2)] - is ok that doesn't add up to 1
        Params.M0 = [ 712 159 ]; % 1x2; year 2015 CO2 stocks (Gt C); default: [ 712 159 ]
    otherwise % Joos et al. (2013), via Dietz et al. (2020)
        Params.Mmatrix = bsxfun(@times,eye(4,4),[1 0.9975 0.9730 0.7927]); % 4x4 CO2 transition matrix; default: bsxfun(@times,eye(4,4),[1 0.9975 0.9730 0.7927])
        Params.emsinks = [0.2173 0.2240 0.2824 0.2763]; % 1x4 allocation of emissions to CO2 reservoirs; default: [0.2173 0.2240 0.2824 0.2763]
        Params.M0 = [ 588+139.1 90.2 29.2 4.2 ]; % 1x4; year 2015 CO2 stocks (Gt C); default: [ 588+139.1 90.2 29.2 4.2 ]
end

switch Params.climatemodel
    case 'dice' % DICE-2016R
        Params.f2x = 3.6813; % forcing from doubled CO2 (W/m^2); default: 3.6813
        Params.lambda = Params.f2x/3.1; % forcing per degree warming (W/m^2/K); default: Params.f2x/3.1
        Params.phi1 = 0.1005;  % warming delay parameter; default: 0.1005
        Params.phi3 = 0.088; % xfer of heat from ocean to surface; default: 0.088
        Params.phi4 = 0.025; % xfer of heat from surface to ocean; default: 0.025
    otherwise % Geoffroy et al. (2013) via Dietz et al. (2020)
        Params.f2x = 3.503; % forcing from doubled CO2 (W/m^2); default: 3.503
        Params.lambda = 1.13; % forcing per degree warming (W/m^2/K); default: 1.13
        Params.phi1 = 0.386;  % warming delay parameter; default: 0.386
        Params.phi3 = 0.73; % xfer of heat from ocean to surface; default: 0.73
        Params.phi4 = 0.034; % xfer of heat from surface to ocean; default: 0.034
end
Params.T0 = 0.85; % year 2015 surface temperature (deg C, rel to 1900); default: 0.85
Params.Tocean0 = 0.0068; % year 2015 lower ocean temperature (deg C, rel to 1900); default: 0.0068
if Params.timestep~=5
    disp(['Warning: Are running with a ' num2str(Params.timestep) '-year timestep instead of a 5-year timestep, so linearized climate transitions are not perfect.']);
end

Params.otherforcing0 = 0.5; % year 2015 non-CO2 forcing (W/m^2); default: 0.5
Params.otherforcing100 = 1; % year 2100 non-CO2 forcing (W/m^2); default: 1

Params.gtc_per_ppm = 2.16; % unit conversion

% Parameters for lognormal distribution of climate sensitivity (see Lemoine 2021, which references Gillingham
% et al. (2018)
Params.mean_cs = 1.10704;
Params.scale_cs = 0.264;
Params.samples = 1e4;


%% Functions

% non-CO2 forcing
Fun.otherforcing = @(t) Params.otherforcing0 + (Params.otherforcing100 - Params.otherforcing0)*min((t-1)*Params.timestep/(17*5),1);

% Mnow is TxSinks, emsnow is Tx1, output is TxSinks
Fun.Mnext = @(Mnow,emsnow) transpose((Params.Mmatrix^Params.timestep)*Mnow') + emsnow*Params.emsinks; % CO2 reservoirs (Gt C)

switch Params.carbonmodel
    case 'dice' % atmospheric CO2 is the first reservoir
        Fun.forcing = @(M,t) Params.f2x*log(M(:,1)/588)/log(2) + Fun.otherforcing(t); % forcing (W/m^2)
    otherwise % atmospheric CO2 is sum of all reservoirs
        Fun.forcing = @(M,t) Params.f2x*log(sum(M,2)/588)/log(2) + Fun.otherforcing(t); % forcing (W/m^2)
end
Fun.Tnext = @(Tnow,Toceannow,Mnext,tnext,lambda) Tnow + (Params.timestep/5)*Params.phi1*( Fun.forcing(Mnext,tnext) - lambda.*Tnow - Params.phi3*(Tnow - Toceannow) ); % surface temperature (deg C, wrt 1900); really should recalibrate if Params.timestep~=5
Fun.Toceannext = @(Tnow,Toceannow) Toceannow + (Params.timestep/5)*Params.phi4*( Tnow - Toceannow ); % lower ocean temperature (deg C, wrt 1900); really should recalibrate if Params.timestep~=5


%% Error check

% make sure emissions is column vector
if size(emissions,2) > size(emissions,1)
    emissions = emissions';
end
if size(emissions,2)~=1
   error('"emissions" should be a vector, not a matrix'); 
end


%% Deterministic trajectories

M = zeros(length(emissions),length(Params.M0));
T = zeros(length(emissions),1);
Tocean = zeros(length(emissions),1);

M(1,:) = Params.M0;
T(1,1) = Params.T0;
Tocean(1,1) = Params.Tocean0;

for t=2:length(emissions)
    M(t,:) = Fun.Mnext(M(t-1,:),emissions(t-1,1));
    T(t,1) = Fun.Tnext(T(t-1,1),Tocean(t-1,1),M(t,:),t,Params.lambda);
    Tocean(t,1) = Fun.Toceannext(T(t-1,1),Tocean(t-1,1));
end

switch Params.carbonmodel
    case 'dice' % atmospheric CO2 is the first reservoir
        M_ppm = M(:,1)/Params.gtc_per_ppm;
    otherwise % atmospheric CO2 is sum of all reservoirs
        M_ppm = sum(M,2)/Params.gtc_per_ppm;
end


%% Temperature percentiles

if quantiles_want>0
    
    cs_draws = normrnd(Params.mean_cs,Params.scale_cs,[1,Params.samples]);
    cs_draws = exp(cs_draws);
    lambda_draws = Params.f2x./cs_draws;
    
    T_draws = zeros(length(emissions),Params.samples);
    Tocean_draws = zeros(length(emissions),Params.samples);
    T_quantiles = zeros(length(emissions),length(quantiles_want));    
    
    T_draws(1,:) = Params.T0;
    Tocean_draws(1,:) = Params.Tocean0;
    T_quantiles(1,:) = Params.T0;
    
    for t=2:length(emissions)
        T_draws(t,:) = Fun.Tnext(T_draws(t-1,:),Tocean_draws(t-1,:),M(t,:),t,lambda_draws);
        Tocean_draws(t,:) = Fun.Toceannext(T_draws(t-1,:),Tocean_draws(t-1,:));
        
        T_quantiles(t,:) = quantile(T_draws(t,:),quantiles_want);
    end
        
else
    
    T_quantiles = [];
    
end
    
end


    %%%%%%%%%%%%

    function update(app)
        %Time series one period is 5 years
        t = 2015:5:2150;

        %Emissions at each period
           EV(1) = app.E2015; Q(1) = 1;
           EV(2) = app.E2030; Q(2) = 4;
           EV(3) = app.E2050; Q(3) = 8;
           EV(4) = app.E2080; Q(4) = 14;
           EV(5) = app.E2115; Q(5) = 21;
           EV(6) = app.E2150; Q(6) = 28; 

        %emissions in CO2 (linear interpolation
         e = interp1(Q,EV, 1:28,'pchip');   


         % Conversion between C and Co2
        co2_c = 44/12;
        c_co2 = 12/44;

        %input takes emissions in C
        if app.Uncertainty_flag==0
        [ M_ppm, T ] = trajectory_climate(app,c_co2* e, 5 );
        else
        [ M_ppm, T ,T_quantiles ] = trajectory_climate(app,c_co2* e, 5 ,[0.05,0.95]);
        end
        %convert M_ppm to co2? 
        %M_ppm = M_ppm * co2_c ;

        %time series and data of past temperture. Annual temperatures from
        %https://www.ncei.noaa.gov/access/monitoring/climate-at-a-glance/global/time-series/globe/land_ocean/ytd/12/1880-2021
        t_TP = 1880:1:2014;
        TP = [0.08 0.12 0.1 0.02 -0.07 -0.05 -0.04 -0.09 0.07 0.12 -0.14 -0.06 -0.11 -0.13 -0.12 -0.05 0.1 0.1 -0.08 0.04 0.12 0.04 -0.06 -0.18 -0.26 -0.08 -0.01 -0.19 -0.23 -0.25 -0.21 -0.25 -0.14 -0.12 0.05 0.1 -0.13 -0.2 -0.11 -0.05 -0.03 0.04 -0.05 -0.05 -0.04 0.02 0.13 0.03 0.02 -0.13 0.09 0.14 0.07 -0.06 0.09 0.04 0.08 0.19 0.18 0.21 0.36 0.47 0.31 0.31 0.45 0.38 0.19 0.17 0.15 0.13 0.05 0.2 0.24 0.33 0.1 0.07 0.02 0.27 0.33 0.28 0.25 0.3 0.31 0.32 0.06 0.13 0.19 0.2 0.17 0.31 0.26 0.13 0.24 0.39 0.14 0.21 0.13 0.41 0.32 0.43 0.48 0.52 0.39 0.56 0.37 0.36 0.44 0.58 0.59 0.5 0.65 0.59 0.44 0.48 0.55 0.67 0.52 0.72 0.85 0.64 0.63 0.77 0.82 0.84 0.78 0.87 0.84 0.82 0.74 0.84 0.92 0.78 0.84 0.87 0.94  ];
        
        %time series and data of past emissions. Data on emissions from 
        %https://data.worldbank.org/indicator/EN.ATM.CO2E.KT
        t_EP = [1990	1991	1992	1993	1994	1995	1996	1997	1998	1999	2000	2001	2002	2003	2004	2005	2006	2007	2008	2009	2010	2011	2012	2013	2014	2015];
        EP = [20625272.97	20766900.89	20796958.43	20937123.28	21052949.1	21652838.85	22110607.51	22492054.36	22613709.04	22720117.59	23445433.27	23845771.45	24165261.26	25285349.24	26434355.26	27414328.02	28302931.66	29368779.2	29595752.4	29267375.19	31043476.98	32021108.26	32460316.86	33119382.99	33198729.82	32995536.02];
        EP = EP/1000000;

        %Time series and data for past atmpsheric carbon ppm
        t_CP = [1950.2509 1952.383918 1953.558561 1953.713937 1953.791544 1954.795965 1956.1572 1957.103747 1957.203374 1957.257539 1957.872424 1958.102786 1958.562971 1959 1959.092696 1960 1960.314045 1960.568662 1961 1961.7159 1962 1962.321916 1962.395341 1962.542092 1962.61528 1963 1963.200018 1963.564496 1964 1965 1965.226349 1965.297878 1965.866012 1966 1966.545235 1966.789044 1967 1968 1968.538749 1968.813869 1968.984371 1969 1969.423218 1970 1970.221683 1970.63715 1970.656302 1970.872682 1971 1972 1972.041018 1972.088334 1973 1974 1974.133413 1975 1975.570921 1976 1976.67037 1976.79675 1977 1978 1979 1979.14259 1980 1981 1982 1983 1983.025919 1983.07685 1984 1984.916753 1985 1985.76218 1986 1986.143228 1986.616016 1987 1987.208592 1988 1988.069512 1988.966335 1989 1989.407968 1989.9714 1990 1991 1991.134817 1992 1992.313517 1993 1993.08 1994 1994.405642 1995 1996 1996.279272 1997 1998 1999 2000 2001 2001.03 2002 2003 2004 2005 2006 2007 2008 2009 2010 2011 2012 2013 2014];
        CP = [312.0017776 312.1844627 312.2227408 312.8003638 313.171057 314.7139613 315.3420739 314.4370578 316.3253802 315.2703546 314.5721663 316.1014029 316.33402 315.98 311.9838924 316.91 313.0933881 318.2065804 317.64 316.5926101 318.45 317.521628 319.1381732 317.4481355 318.6110626 318.99 319.0660644 319.123473 319.62 320.04 319.8929363 319.1950596 320.9815664 321.37 322.1747797 320.4923554 322.18 323.05 320.3806508 324.0855174 325.0771759 324.62 324.7060576 325.68 324.5570617 324.9059937 326.6491948 326.3093257 326.32 327.46 330.3504422 328.415888 329.68 331.9658334 331.5498057 332.3854167 332.3656863 331.65 332.3689555 335.5469535 332.9170667 334.7205341 336.5891781 334.8485555 338.1110667 339.5620333 340.55105 342.0726621 339.4604885 341.3292967 343.9083333 342.0519764 345.1715666 343.1107521 346.5817333 343.9736768 344.7177686 348.1685833 345.441206 350.5374167 347.5974056 349.2784146 352.1553833 349.8002704 350.8086522 353.33075 354.71875 352.4163084 355.6226833 353.7242358 357.0414048 353.9466853 358.5470833 357.1067397 360.5675238 362.1199643 359.6477932 363.318526 365.4163317 368.0545119 369.2876548 370.4308611 368.0224879 372.3419621 374.8154 376.62205 378.6644667 380.7308833 382.60645 384.645 386.1561667 388.3061955 390.2463909 392.3017 395.0600167 397.0520167];
        
        if app.Uncertainty_flag==0
            plot(app.UITemperture,t_TP,TP,'b')
            hold(app.UITemperture,"on")
            plot(app.UITemperture,t,T','r')
            hold(app.UITemperture,"off")
            xlim(app.UITemperture,[min(t_TP),max(t)])
            legend(app.UITemperture,{'Past data','Prediction'},'Location','southeast')
        else
            
            plot(app.UITemperture,t_TP,TP,'b')
            hold(app.UITemperture,"on")          
            plot(app.UITemperture,t,T_quantiles(:,2)','Color','r','LineStyle','-.')
            plot(app.UITemperture,t,T_quantiles(:,1)','Color','r','LineStyle','--')
            xlim(app.UITemperture,[min(t_TP),max(t)])
            hold(app.UITemperture,"off")
            legend(app.UITemperture,{'Past data','95th percentile prediction','5th percentile prediction'},'Location','southeast')
        end
        
        plot(app.UIEmissions,t_EP,EP,'b')
        hold(app.UIEmissions,"on")
        plot(app.UIEmissions,t,e,'r')
        plot(app.UIEmissions,2030,app.E2030,'o','MarkerFaceColor', 'r')
        plot(app.UIEmissions,2050,app.E2050,'o','MarkerFaceColor', 'r')
        plot(app.UIEmissions,2080,app.E2080,'o','MarkerFaceColor', 'r')
        plot(app.UIEmissions,2115,app.E2115,'o','MarkerFaceColor', 'r')
        plot(app.UIEmissions,2150,app.E2150,'o','MarkerFaceColor', 'r')
        ylim(app.UIEmissions,[min(0,min(e)),max(e)])
        hold(app.UIEmissions,"off")
        legend(app.UIEmissions,{'Past data','Prediction'},'Location','southwest')

        plot(app.UICarbon,t_CP,CP,'b')
        hold(app.UICarbon,"on")
        plot(app.UICarbon,t,M_ppm,'r')
        plot(app.UICarbon,[min(t_CP),max(t)],[280,280])
        ylim(app.UICarbon,[0,max(M_ppm)])
        hold(app.UICarbon,"off")
        legend(app.UICarbon,{'Past data','Prediction','Pre-industrial'},'Location','southeast')
       
        return
        end
       
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function start(app)
            update(app)
        end

        % Value changing function: In2030Slider
        function In2030SliderValueChanging(app, event)
            changingValue = event.Value;
            app.E2030 = changingValue;
            update(app);
        end

        % Value changing function: In2050Slider
        function In2050SliderValueChanging(app, event)
            changingValue = event.Value;
            app.E2050 = changingValue;
            update(app);
        end

        % Value changing function: In2080Slider
        function In2080SliderValueChanging(app, event)
            changingValue = event.Value;
            app.E2080 = changingValue;
            update(app);
        end

        % Value changing function: In2115Slider
        function In2115SliderValueChanging(app, event)
            changingValue = event.Value;
            app.E2115 = changingValue;
            update(app);
        end

        % Value changing function: In2150Slider
        function In2150SliderValueChanging(app, event)
            changingValue = event.Value;
            app.E2150 = changingValue;
            update(app);
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            switch app.Switch.Value
            case 'On'
                app.Uncertainty_flag = 1;
            case 'Off'
                app.Uncertainty_flag = 0;
            end
    
            update(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UICarbon = uiaxes(app.UIFigure);
            title(app.UICarbon, 'Atmospheric Carbon (ppm)')
            xlabel(app.UICarbon, 'Year')
            zlabel(app.UICarbon, 'Z')
            app.UIEmissions = uiaxes(app.UIFigure);
            title(app.UIEmissions, 'Annual Emissions (Gt CO2)')
            xlabel(app.UIEmissions, 'Year')
            zlabel(app.UIEmissions, 'Z')
            app.UITemperture = uiaxes(app.UIFigure);
            title(app.UITemperture, 'Temperature (deg C) above 1880-1890 average ')
            xlabel(app.UITemperture, 'Year')
            zlabel(app.UITemperture, 'Z')
            app.AnnualEmissionsGtofCO2Panel = uipanel(app.UIFigure);
            app.AnnualEmissionsGtofCO2Panel.Title = 'Annual Emissions (Gt of CO2)';
            app.Emissionsin2047Label = uilabel(app.AnnualEmissionsGtofCO2Panel);
            app.Emissionsin2047Label.Text = 'In 2050';
            app.In2050Slider = uislider(app.AnnualEmissionsGtofCO2Panel);
            app.In2050Slider.Limits = [-50 150];
            app.In2050Slider.ValueChangingFcn = createCallbackFcn(app, @In2050SliderValueChanging, true);
            app.In2050Slider.Value = 60;
            app.In2080Label = uilabel(app.AnnualEmissionsGtofCO2Panel);
            app.In2080Label.Text = 'In 2080';
            app.In2080Slider = uislider(app.AnnualEmissionsGtofCO2Panel);
            app.In2080Slider.Limits = [-50 150];
            app.In2080Slider.ValueChangingFcn = createCallbackFcn(app, @In2080SliderValueChanging, true);
            app.In2080Slider.Value = 60;
            app.In2115Label = uilabel(app.AnnualEmissionsGtofCO2Panel);
            app.In2115Label.Text = 'In 2115';
            app.In2115Slider = uislider(app.AnnualEmissionsGtofCO2Panel);
            app.In2115Slider.Limits = [-50 150];
            app.In2115Slider.ValueChangingFcn = createCallbackFcn(app, @In2115SliderValueChanging, true);
            app.In2115Slider.Value = 40;
            app.In2150Label = uilabel(app.AnnualEmissionsGtofCO2Panel);
            app.In2150Label.Text = 'In 2150';
            app.In2150Slider = uislider(app.AnnualEmissionsGtofCO2Panel);
            app.In2150Slider.Limits = [-50 150];
            app.In2150Slider.ValueChangingFcn = createCallbackFcn(app, @In2150SliderValueChanging, true);
            app.In2150Slider.Value = 35;
            app.Switch = uiswitch(app.AnnualEmissionsGtofCO2Panel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.UncertaintyLabel = uilabel(app.AnnualEmissionsGtofCO2Panel);
            app.UncertaintyLabel.Text = 'Uncertainty';
            app.In2030SliderLabel = uilabel(app.AnnualEmissionsGtofCO2Panel);
            app.In2030SliderLabel.Text = 'In 2030';
            app.In2030Slider = uislider(app.AnnualEmissionsGtofCO2Panel);
            app.In2030Slider.Limits = [-50 150];
            app.In2030Slider.ValueChangingFcn = createCallbackFcn(app, @In2030SliderValueChanging, true);
            app.In2030Slider.Value = 45;
            app.AboutPanel_2 = uipanel(app.UIFigure);
            app.AboutPanel_2.Title = 'About';
            app.Hyperlink = uihyperlink(app.AboutPanel_2);
            app.Hyperlink.URL = 'https://data.worldbank.org/indicator/EN.ATM.CO2E.KT';
            app.Hyperlink.Text = 'The World Bank';
            app.Hyperlink_2 = uihyperlink(app.AboutPanel_2);
            app.Hyperlink_2.URL = 'https://www.epa.gov/climate-indicators/climate-change-indicators-atmospheric-concentrations-greenhouse-gases';
            app.Hyperlink_2.Text = 'US Environmental Protection Agency';
            app.Hyperlink_3 = uihyperlink(app.AboutPanel_2);
            app.Hyperlink_3.URL = 'https://www.ncei.noaa.gov/access/monitoring/climate-at-a-glance/global/time-series/globe/land_ocean/ytd/12/1880-2021';
            app.Hyperlink_3.Text = 'National Centers For Environmental Info.';
            app.EmissionsLabel = uilabel(app.AboutPanel_2);
            app.EmissionsLabel.Text = 'Emissions:';
            app.AtmosphericCO2Label = uilabel(app.AboutPanel_2);
            app.AtmosphericCO2Label.Text = 'Atmospheric CO2:';
            app.TemperatureLabel = uilabel(app.AboutPanel_2);
            app.TemperatureLabel.Text = 'Temperature:';
            app.DatasourcesLabel = uilabel(app.AboutPanel_2);
            app.DatasourcesLabel.Text = 'Data sources:';
            app.Hyperlink_7 = uihyperlink(app.AboutPanel_2);
            app.Hyperlink_7.URL = 'https://www.dereklemoine.com/';
            app.Hyperlink_7.Text = 'Derek Lemoine';
            app.Hyperlink_8 = uihyperlink(app.AboutPanel_2);
            app.Hyperlink_8.URL = 'www.ernestoriveramora.com';
            app.Hyperlink_8.Text = 'Ernesto Rivera Mora';
            app.AuthorsLabel_2 = uilabel(app.AboutPanel_2);
            app.AuthorsLabel_2.Text = 'Authors:';
            app.ForlastversionclickLabel_2 = uilabel(app.AboutPanel_2);
            app.ForlastversionclickLabel_2.Text = 'For last version click';
            app.Hyperlink_9 = uihyperlink(app.AboutPanel_2);
            app.Hyperlink_9.URL = 'https://github.com/dlemoine1/Econ150C2';
            app.Hyperlink_9.Text = 'here';
            app.FutureClimateChangePathwaysLabel = uilabel(app.UIFigure);
            app.FutureClimateChangePathwaysLabel.Text = 'Future Climate Change Pathways';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Future_Climate_Change_Pathways

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @start)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end